classdef milkweed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ChooseRuntoLoadLabel  matlab.ui.control.Label
        RunSelect             matlab.ui.control.DropDown
        PlotArea              matlab.ui.control.UIAxes
        LoadButton            matlab.ui.control.Button
        PlotTypeLabel         matlab.ui.control.Label
        PlotSelect            matlab.ui.control.DropDown
        SummaryTextAreaLabel  matlab.ui.control.Label
        SummaryTextArea       matlab.ui.control.TextArea
        PlotButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        

    end
            
    %% Helper Variables
    properties (Access = public)
        run_data
    end
    %% Helper Functions
    methods (Access = public)
        
        function runs = run_lister(app)
            cd run_storage
            temp = dir('*.mat');
            [num_stored,~] = size(temp);
            
            
            runs = cell(int8(num_stored + 1), 1);
            runs{1} = '---';
            for i = 1:num_stored
                parts = strsplit(temp(i).name, '.');
                runs{i+1} = parts{1};
            end
            cd ../
        end
        
        function run_data = load_run(app, run_name)
            run_name = strcat(run_name, '.mat');
            cd run_storage;
            dump = load(run_name);
            cd ../
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runs = run_lister(app);
            app.RunSelect.Items = runs;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            run_name = convertCharsToStrings(app.RunSelect.Value);
            app.run_data = load_run(app, run_name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseRuntoLoadLabel = uilabel(app.UIFigure);
            app.ChooseRuntoLoadLabel.Text = {'Choose Run to Load'; ''};
            app.RunSelect = uidropdown(app.UIFigure);
            app.RunSelect.Items = {'---'};
            app.RunSelect.Value = '---';
            app.PlotArea = uiaxes(app.UIFigure);
            title(app.PlotArea, '')
            xlabel(app.PlotArea, 'Initial Rates [deg/s]')
            ylabel(app.PlotArea, {'Final Rates [deg/s]'; ''})
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotTypeLabel = uilabel(app.UIFigure);
            app.PlotTypeLabel.Text = {'Plot Type'; ''};
            app.PlotSelect = uidropdown(app.UIFigure);
            app.PlotSelect.Items = {'---', 'All Rates', 'omega_x', 'omega_y', 'omega_z', 'Rates in Excess'};
            app.PlotSelect.Value = '---';
            app.SummaryTextAreaLabel = uilabel(app.UIFigure);
            app.SummaryTextAreaLabel.Text = {'Summary'; ''};
            app.SummaryTextArea = uitextarea(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = milkweed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end